classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TheSarnaProjectUIFigure         matlab.ui.Figure
        SerialConfigurationPanel        matlab.ui.container.Panel
        ConnectButton                   matlab.ui.control.StateButton
        BaudrateEditFieldLabel          matlab.ui.control.Label
        BaudrateEditField               matlab.ui.control.NumericEditField
        PortDropDownLabel               matlab.ui.control.Label
        PortDropDown                    matlab.ui.control.DropDown
        ManualControlPanel              matlab.ui.container.Panel
        MotorStopButton                 matlab.ui.control.Button
        ThrottlePanel                   matlab.ui.container.Panel
        MotorThrottle                   matlab.ui.control.LinearGauge
        MotorThrottleUpButton           matlab.ui.control.Button
        MotorThrottleDownButton         matlab.ui.control.Button
        ServoPanel                      matlab.ui.container.Panel
        ServoDirection                  matlab.ui.control.LinearGauge
        ServoLeftButton                 matlab.ui.control.Button
        ServoRightButton                matlab.ui.control.Button
        ObstacledetectedleftLampLabel   matlab.ui.control.Label
        ObstacledetectedleftLamp        matlab.ui.control.Lamp
        ObstacledetectedrightLampLabel  matlab.ui.control.Label
        ObstacledetectedrightLamp       matlab.ui.control.Lamp
        StoppatterndetectedLampLabel    matlab.ui.control.Label
        StoppatterndetectedLamp         matlab.ui.control.Lamp
        SlowDownpatterndetectedLampLabel  matlab.ui.control.Label
        SlowDownpatterndetectedLamp     matlab.ui.control.Lamp
        SpeeduppatterndetectedLampLabel  matlab.ui.control.Label
        SpeeduppatterndetectedLamp      matlab.ui.control.Lamp
        emergencybreakdetectedLampLabel  matlab.ui.control.Label
        emergencybreakdetectedLamp      matlab.ui.control.Lamp
        crossroaddetectedLampLabel      matlab.ui.control.Label
        crossroaddetectedLamp           matlab.ui.control.Lamp
        LeftlineEditFieldLabel          matlab.ui.control.Label
        LeftlineEditField               matlab.ui.control.NumericEditField
        RightLineEditFieldLabel         matlab.ui.control.Label
        RightLineEditField              matlab.ui.control.NumericEditField
        CameraAces                      matlab.ui.control.UIAxes
        MotorLeftAces                   matlab.ui.control.UIAxes
        MotorRightAces                  matlab.ui.control.UIAxes
        leftEditFieldLabel              matlab.ui.control.Label
        leftEditField                   matlab.ui.control.NumericEditField
        rightEditFieldLabel             matlab.ui.control.Label
        rightEditField                  matlab.ui.control.NumericEditField
        encoderleftEditFieldLabel       matlab.ui.control.Label
        encoderleftEditField            matlab.ui.control.NumericEditField
        encoderrightEditFieldLabel      matlab.ui.control.Label
        encoderrightEditField           matlab.ui.control.NumericEditField
        servoEditFieldLabel             matlab.ui.control.Label
        servoEditField                  matlab.ui.control.NumericEditField
        ServoDirectionRead              matlab.ui.control.LinearGauge
    end

    
    properties (Access = private)
        serial_connection % Description
        line_camera_SamplesPerLine = 128 % Description
        line_camera_LineStarted = false % Description
        line_camera_LineSamples;  % Description
        SampleCount = 128;
        minFrameLen = 24;
        line_camera_Samples;
        motor_left_Samples;
        motor_right_Samples;
        encoder_left_Samples;
        encoder_right_Samples;
    end
    
    methods (Access = private)
        
        
        function onSerialPortData(app, ~, ~)
            tic
            issof = 1;
            sof = 255;
           % disp("serial check");
                for n=1:4
                    if sof ~= read(app.serial_connection, 1, "uint8")
                        %disp("scheck");
                        issof = 0;
                        break;
                    end
                end
            if  1 == issof
                %app.counter = app.counter + 1;
                %disp("sof");
                payloadLength = read(app.serial_connection, 1, "uint16");
                frame = read(app.serial_connection, payloadLength, "uint8");
                %disp(frame);
                %lines
                lineLeft = uint8(frame(1));
                lineRight = uint8(frame(2));
                plot(app.CameraAces, lineLeft, 1, "*r", lineRight, 1, "*g");
                app.LeftlineEditField.Value = double(lineLeft);
                app.RightLineEditField.Value = double(lineRight);
                % motors & encoders
                motorLeft = double(int16(frame(3)) + bitshift(int16(frame(4)), 8)) / 100;
                motorRight = double(int16(frame(5)) + bitshift(int16(frame(6)), 8)) / 100;
                
                encoderIdealLeft = 454.375*motorLeft - 71.875;
                encoderIdealRight = 454.375*motorRight - 71.875;
                
                encoderLeft = double(int16(frame(7)) + bitshift(int16(frame(8)), 8)) ;
                encoderRight = double(int16(frame(9)) + bitshift(int16(frame(10)), 8));
                
                app.motor_left_Samples = [app.motor_left_Samples(2:end), motorLeft];
                app.motor_right_Samples = [app.motor_right_Samples(2:end), motorRight];
                app.encoder_left_Samples = [app.encoder_left_Samples(2:end), encoderLeft];
                app.encoder_right_Samples = [app.encoder_right_Samples(2:end), encoderRight];
                %if(app.counter == 100)
                %    for n=1:100
                %        app.bufferLeft(n) = app.bufferLeft(n+1);
                %        app.bufferRight(n) = app.bufferRight(n+1);
                %    end
                %    app.counter = app.counter - 1;
                %end
                
                %disp("counter");
                %disp(app.counter);
                %app.bufferMotorLeft(app.counter) = (motorLeft);
                %app.bufferMotorRight(app.counter) = (motorRight);
                %app.bufferEncoderLeft(app.counter) = (encoderLeft);
                %app.bufferEncoderRight(app.counter) = (encoderRight);
                   
                 plot(app.MotorLeftAces, app.encoder_left_Samples , "-b");
                 plot(app.MotorRightAces, app.encoder_right_Samples, "-b");
                 yline(app.MotorLeftAces, encoderIdealLeft, "-r");
                 yline(app.MotorRightAces, encoderIdealRight, "-r");
                 ylim(app.MotorLeftAces, [(encoderIdealLeft - 30) (encoderIdealLeft + 30)]);
                 ylim(app.MotorRightAces, [(encoderIdealRight - 30) (encoderIdealRight + 30)]);
%                 plot(app.MotorLeftAces, app.bufferEncoderLeft, "-r");
%                 plot(app.MotorRightAces, app.bufferEncoderRight, "-r");
                app.leftEditField.Value = motorLeft;
                app.encoderleftEditField.Value = encoderLeft;
                app.rightEditField.Value = motorRight;
                app.encoderrightEditField.Value = encoderRight;
                % servo
                servoValue = double(int16(frame(11)) + bitshift(int16(frame(12)), 8)) / 100;
                app.servoEditField.Value = servoValue;
                app.ServoDirectionRead.Value = servoValue;
                % obsctacle
                if double(frame(13)) == 1
                    app.ObstacledetectedleftLamp.Enable = 1;
                    app.ObstacledetectedrightLamp.Enable = 0;
                else
                    app.ObstacledetectedleftLamp.Enable = 0;
                    app.ObstacledetectedrightLamp.Enable = 1;
                end
                % speed up
                app.SpeeduppatterndetectedLamp.Enable = double(frame(14));
                % slow down
                app.SlowDownpatterndetectedLamp.Enable = double(frame(15));
                % emergency break
                app.emergencybreakdetectedLamp.Enable = double(frame(16));
                %stop pattern
                app.StoppatterndetectedLamp.Enable = double(frame(17));
                % crossroad
                app.crossroaddetectedLamp.Enable = double(frame(18));
            end
            toc
        end
        
        function sendThrottleValue(app)
            str = sprintf("m %.2f\n", app.MotorThrottle.Value);
            write(app.serial_connection,str, "string");
        end
        
        function sendServoValue(app)
            str = sprintf("s %.2f\n", app.ServoDirection.Value);
            write(app.serial_connection,str, "string");
        end
        
        function results = sendEmergencyStop(app)
            write(app.serial_connection, "st" + newline, "string");
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.line_camera_Samples = zeros(app.line_camera_SamplesPerLine);
            app.motor_left_Samples = zeros(1, app.SampleCount);
            app.motor_right_Samples = zeros(1, app.SampleCount);
            app.encoder_left_Samples = zeros(1, app.SampleCount);
            app.encoder_right_Samples = zeros(1, app.SampleCount);
            app.PortDropDown.Items = serialportlist();
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            if 1 == value
                disp("Connecting");
                app.serial_connection = serialport(app.PortDropDown.Value, app.BaudrateEditField.Value);
                disp("Connected");
                app.serial_connection.Timeout = 0.5;
                configureCallback(app.serial_connection, "byte",app.minFrameLen, @app.onSerialPortData);
                app.ConnectButton.Text = "Disconnect";
            else
                disp("Closing connection");
                configureCallback(app.serial_connection,'off');
                
                app.serial_connection = {}; % close serial port
                disp("Connection closed");
                app.ConnectButton.Text = "Connect";
            end
            
            app.MotorThrottleUpButton.Enable = value;
            app.MotorThrottleDownButton.Enable = value;
            app.MotorStopButton.Enable = value;
            app.ServoLeftButton.Enable = value;
            app.ServoRightButton.Enable = value;
            app.MotorThrottle.Value = 0;
            app.ServoDirection.Value = 0;
            
        end

        % Key press function: TheSarnaProjectUIFigure
        function TheSarnaProjectUIFigureKeyPress(app, event)
            disp(event.Key)
            switch event.Key
                case 'uparrow'
                    app.MotorThrottleUpButtonPushed(event);
                case 'downarrow'
                    app.MotorThrottleDownButtonPushed(event);
                case 'rightarrow'
                    app.ServoRightButtonPushed(event);
                case 'leftarrow'
                    app.ServoLeftButtonPushed(event);
                case 'space'
                    app.MotorStopButtonPushed(event);
            end


        end

        % Button pushed function: MotorThrottleUpButton
        function MotorThrottleUpButtonPushed(app, event)
            switch app.MotorThrottleUpButton.Enable
                case 'on'
               disp("accelerate")
               app.MotorThrottle.Value = median([app.MotorThrottle.Value + 0.05,  app.MotorThrottle.Limits]);
               app.sendThrottleValue;
            end
        end

        % Button pushed function: MotorThrottleDownButton
        function MotorThrottleDownButtonPushed(app, event)
            switch app.MotorThrottleDownButton.Enable
                case 'on'
                disp("decelerate")
                app.MotorThrottle.Value = median([app.MotorThrottle.Value - 0.05,  app.MotorThrottle.Limits]);
                app.sendThrottleValue;
            end
        end

        % Button pushed function: MotorStopButton
        function MotorStopButtonPushed(app, event)
            switch app.MotorStopButton.Enable
                case 'on'
                    
                    app.sendEmergencyStop
                    app.MotorThrottle.Value = 0;
                    app.ServoDirection.Value = 0;
                disp("== EMG BREAK ==")
            end
        end

        % Button pushed function: ServoLeftButton
        function ServoLeftButtonPushed(app, event)
        switch app.ServoLeftButton.Enable
        case 'on'
            disp("left");
            app.ServoDirection.Value = median([app.ServoDirection.Value - 0.05, app.ServoDirection.Limits]);
            app.sendServoValue;
        end
        end

        % Button pushed function: ServoRightButton
        function ServoRightButtonPushed(app, event)
        switch app.ServoRightButton.Enable
        case 'on'
            disp("right");
            app.ServoDirection.Value = median([app.ServoDirection.Value + 0.05, app.ServoDirection.Limits]);
            app.sendServoValue;
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TheSarnaProjectUIFigure = uifigure('Visible', 'off');
            app.TheSarnaProjectUIFigure.Name = 'The Sarna Project';
            app.TheSarnaProjectUIFigure.KeyPressFcn = createCallbackFcn(app, @TheSarnaProjectUIFigureKeyPress, true);
            app.SerialConfigurationPanel = uipanel(app.TheSarnaProjectUIFigure);
            app.SerialConfigurationPanel.Title = 'Serial Configuration';
            app.ConnectButton = uibutton(app.SerialConfigurationPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.BaudrateEditFieldLabel = uilabel(app.SerialConfigurationPanel);
            app.BaudrateEditFieldLabel.Text = 'Baudrate';
            app.BaudrateEditField = uieditfield(app.SerialConfigurationPanel, 'numeric');
            app.BaudrateEditField.Limits = [0 Inf];
            app.BaudrateEditField.ValueDisplayFormat = '%d';
            app.BaudrateEditField.Value = 115200;
            app.PortDropDownLabel = uilabel(app.SerialConfigurationPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.SerialConfigurationPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            app.ManualControlPanel = uipanel(app.TheSarnaProjectUIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.MotorStopButton = uibutton(app.ManualControlPanel, 'push');
            app.MotorStopButton.ButtonPushedFcn = createCallbackFcn(app, @MotorStopButtonPushed, true);
            app.MotorStopButton.Enable = 'off';
            app.MotorStopButton.Text = {'Emergency'; 'breaking'};
            app.ThrottlePanel = uipanel(app.ManualControlPanel);
            app.ThrottlePanel.Title = 'Throttle';
            app.MotorThrottle = uigauge(app.ThrottlePanel, 'linear');
            app.MotorThrottle.Limits = [-1 1];
            app.MotorThrottle.MajorTicks = [-1 0 1];
            app.MotorThrottle.MajorTickLabels = {'REV', 'N', 'FWD'};
            app.MotorThrottleUpButton = uibutton(app.ThrottlePanel, 'push');
            app.MotorThrottleUpButton.ButtonPushedFcn = createCallbackFcn(app, @MotorThrottleUpButtonPushed, true);
            app.MotorThrottleUpButton.Enable = 'off';
            app.MotorThrottleUpButton.Text = 'FWD';
            app.MotorThrottleDownButton = uibutton(app.ThrottlePanel, 'push');
            app.MotorThrottleDownButton.ButtonPushedFcn = createCallbackFcn(app, @MotorThrottleDownButtonPushed, true);
            app.MotorThrottleDownButton.Enable = 'off';
            app.MotorThrottleDownButton.Text = 'REV';
            app.ServoPanel = uipanel(app.ManualControlPanel);
            app.ServoPanel.Title = 'Servo';
            app.ServoDirection = uigauge(app.ServoPanel, 'linear');
            app.ServoDirection.Limits = [-1 1];
            app.ServoDirection.MajorTicks = [-1 0 1];
            app.ServoDirection.MajorTickLabels = {'L', 'N', 'R'};
            app.ServoLeftButton = uibutton(app.ServoPanel, 'push');
            app.ServoLeftButton.ButtonPushedFcn = createCallbackFcn(app, @ServoLeftButtonPushed, true);
            app.ServoLeftButton.Enable = 'off';
            app.ServoLeftButton.Text = 'L';
            app.ServoRightButton = uibutton(app.ServoPanel, 'push');
            app.ServoRightButton.ButtonPushedFcn = createCallbackFcn(app, @ServoRightButtonPushed, true);
            app.ServoRightButton.Enable = 'off';
            app.ServoRightButton.Text = 'R';
            app.ObstacledetectedleftLampLabel = uilabel(app.ManualControlPanel);
            app.ObstacledetectedleftLampLabel.Text = 'Obstacle detected left';
            app.ObstacledetectedleftLamp = uilamp(app.ManualControlPanel);
            app.ObstacledetectedrightLampLabel = uilabel(app.ManualControlPanel);
            app.ObstacledetectedrightLampLabel.Text = 'Obstacle detected right';
            app.ObstacledetectedrightLamp = uilamp(app.ManualControlPanel);
            app.StoppatterndetectedLampLabel = uilabel(app.ManualControlPanel);
            app.StoppatterndetectedLampLabel.Text = 'Stop pattern detected';
            app.StoppatterndetectedLamp = uilamp(app.ManualControlPanel);
            app.SlowDownpatterndetectedLampLabel = uilabel(app.ManualControlPanel);
            app.SlowDownpatterndetectedLampLabel.Text = 'Slow Down pattern detected';
            app.SlowDownpatterndetectedLamp = uilamp(app.ManualControlPanel);
            app.SpeeduppatterndetectedLampLabel = uilabel(app.ManualControlPanel);
            app.SpeeduppatterndetectedLampLabel.Text = 'Speed up pattern detected';
            app.SpeeduppatterndetectedLamp = uilamp(app.ManualControlPanel);
            app.emergencybreakdetectedLampLabel = uilabel(app.ManualControlPanel);
            app.emergencybreakdetectedLampLabel.Text = 'emergency break detected';
            app.emergencybreakdetectedLamp = uilamp(app.ManualControlPanel);
            app.crossroaddetectedLampLabel = uilabel(app.ManualControlPanel);
            app.crossroaddetectedLampLabel.Text = 'crossroad detected';
            app.crossroaddetectedLamp = uilamp(app.ManualControlPanel);
            app.LeftlineEditFieldLabel = uilabel(app.TheSarnaProjectUIFigure);
            app.LeftlineEditFieldLabel.Text = 'Left line';
            app.LeftlineEditField = uieditfield(app.TheSarnaProjectUIFigure, 'numeric');
            app.LeftlineEditField.ValueDisplayFormat = '%.0f';
            app.RightLineEditFieldLabel = uilabel(app.TheSarnaProjectUIFigure);
            app.RightLineEditFieldLabel.Text = 'Right Line';
            app.RightLineEditField = uieditfield(app.TheSarnaProjectUIFigure, 'numeric');
            app.CameraAces = uiaxes(app.TheSarnaProjectUIFigure);
            title(app.CameraAces, 'Camera')
            xlabel(app.CameraAces, 'pixels')
            ylabel(app.CameraAces, 'Line')
            app.MotorLeftAces = uiaxes(app.TheSarnaProjectUIFigure);
            title(app.MotorLeftAces, 'Motor Left')
            xlabel(app.MotorLeftAces, 'Motor value')
            ylabel(app.MotorLeftAces, 'Encoder Value')
            app.MotorRightAces = uiaxes(app.TheSarnaProjectUIFigure);
            title(app.MotorRightAces, 'Motor Right')
            xlabel(app.MotorRightAces, 'Motor value')
            ylabel(app.MotorRightAces, 'Encoder Value')
            app.leftEditFieldLabel = uilabel(app.TheSarnaProjectUIFigure);
            app.leftEditFieldLabel.Text = 'left';
            app.leftEditField = uieditfield(app.TheSarnaProjectUIFigure, 'numeric');
            app.rightEditFieldLabel = uilabel(app.TheSarnaProjectUIFigure);
            app.rightEditFieldLabel.Text = 'right';
            app.rightEditField = uieditfield(app.TheSarnaProjectUIFigure, 'numeric');
            app.encoderleftEditFieldLabel = uilabel(app.TheSarnaProjectUIFigure);
            app.encoderleftEditFieldLabel.Text = 'encoder left';
            app.encoderleftEditField = uieditfield(app.TheSarnaProjectUIFigure, 'numeric');
            app.encoderrightEditFieldLabel = uilabel(app.TheSarnaProjectUIFigure);
            app.encoderrightEditFieldLabel.Text = 'encoder right';
            app.encoderrightEditField = uieditfield(app.TheSarnaProjectUIFigure, 'numeric');
            app.servoEditFieldLabel = uilabel(app.TheSarnaProjectUIFigure);
            app.servoEditFieldLabel.Text = 'servo';
            app.servoEditField = uieditfield(app.TheSarnaProjectUIFigure, 'numeric');
            app.ServoDirectionRead = uigauge(app.TheSarnaProjectUIFigure, 'linear');
            app.ServoDirectionRead.Limits = [-1 1];
            app.ServoDirectionRead.MajorTicks = [-1 0 1];
            app.ServoDirectionRead.MajorTickLabels = {'L', 'N', 'R'};
            % Show the figure after all components are created
            app.TheSarnaProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TheSarnaProjectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TheSarnaProjectUIFigure)
        end
    end
end